classdef ELEC_291_Project_1_Reflow_Oven_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CLOSEButton                   matlab.ui.control.Button
        ErrorTimeoutsSlider           matlab.ui.control.Slider
        ErrorTimeoutsSliderLabel      matlab.ui.control.Label
        ErrorTimeoutsSpinner          matlab.ui.control.Spinner
        ReflowTimesSlider             matlab.ui.control.Slider
        ReflowTimesSliderLabel        matlab.ui.control.Label
        ReflowTimesSpinner            matlab.ui.control.Spinner
        ReflowTemperatureSlider       matlab.ui.control.Slider
        ReflowTemperatureSliderLabel  matlab.ui.control.Label
        ReflowTemperatureSpinner      matlab.ui.control.Spinner
        SoakingTimesSlider            matlab.ui.control.Slider
        SoakingTimeLabel              matlab.ui.control.Label
        SoakingTimesSpinner           matlab.ui.control.Spinner
        SoakingTemperatureSlider      matlab.ui.control.Slider
        SampleCountLabel              matlab.ui.control.Label
        SoakingTemperatureSpinner     matlab.ui.control.Spinner
        Lamp                          matlab.ui.control.Lamp
        ProjectLabel                  matlab.ui.control.Label
        OPENButton                    matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        STARTButton                   matlab.ui.control.Button
        ABORTButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        BuadRateEditField             matlab.ui.control.NumericEditField
        BuadRateEditFieldLabel        matlab.ui.control.Label
        COMPortDropDown               matlab.ui.control.DropDown
        COMPortDropDownLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serialportopen
        abortflag
        currenttemp
    end
    
    methods (Access = private)
        
        function ClosePortSR(app)
            app.serialportopen = 0;

            app.TextArea.Value = {'No COM port opened.'};
            app.BuadRateEditField.Enable = 'on';
            app.RefreshButton.Enable = 'on';
            app.COMPortDropDown.Enable = 'on';

            app.OPENButton.Visible = 'on';
            app.CLOSEButton.Visible = 'off';

            app.STARTButton.Enable = 'off';
            app.STARTButton.Visible = 'off';
            app.ABORTButton.Enable = 'off';
            app.ABORTButton.Visible = 'off';
            app.Lamp.Visible = "off";

            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        function OpenPortSR(app)
            app.TextArea.Value = {'COM port sucessfully opened.'};
            app.BuadRateEditField.Enable = 'off';
            app.RefreshButton.Enable = 'off';
            app.COMPortDropDown.Enable = 'off';

            app.OPENButton.Visible = 'off';
            app.CLOSEButton.Visible = 'on';

            app.STARTButton.Enable = 'on';
            app.STARTButton.Visible = 'on';
            app.STARTButton.Text = 'START';

            app.ABORTButton.Enable = 'off';
            app.ABORTButton.Visible = 'on';
            app.Lamp.Visible = "on";
        end

        function StartDataLog(app)
            app.abortflag = 0;
            a = plot(app.UIAxes,1,1);
            delete(a);
            if app.serialportopen ~= 0
                app.STARTButton.Enable = 'off';
                app.ABORTButton.Enable = 'on';
                app.STARTButton.Text = 'Running...';
                flush(app.serialportopen);
                x = 1:1000000;
                y = 0*(1:1000000);
                z = 0*(1:1000000);
                hLine = plot(app.UIAxes,x,y);
                for i=1:1000000
                    if app.abortflag == 1
                        break;
                    end
                    y(i) = fscanf(app.serialportopen,"%d")/100.00;
	                stripchart('Update',hLine,y(i))
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            if (strfind(app.COMPortDropDown.Value,'COM') ~= 0)
                if (app.BuadRateEditField.Value > 0)
                    app.serialportopen = serialport(app.COMPortDropDown.Value,app.BuadRateEditField.Value);
                    OpenPortSR(app);
                    StartDataLog(app);
                else
                    app.TextArea.Value = {'Baud rate cannot be negative.'};
                end
            else    
                app.TextArea.Value = {'No valid COM Port found.'};
            end
            
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            app.abortflag = 1;
            ClosePortSR(app);
        end

        % Value changed function: SoakingTemperatureSpinner
        function SoakingTemperatureSpinnerValueChanged(app, event)
            app.SoakingTemperatureSlider.Value = app.SoakingTemperatureSpinner.Value;
        end

        % Value changed function: SoakingTemperatureSlider
        function SoakingTemperatureSliderValueChanged(app, event)
            app.SoakingTemperatureSlider.Value = round(app.SoakingTemperatureSlider.Value);
            app.SoakingTemperatureSpinner.Value = app.SoakingTemperatureSlider.Value;
        end

        % Value changed function: SoakingTimesSpinner
        function SoakingTimesSpinnerValueChanged(app, event)
            app.SoakingTimesSlider.Value = app.SoakingTimesSpinner.Value;
        end

        % Value changed function: ReflowTemperatureSpinner
        function ReflowTemperatureSpinnerValueChanged(app, event)
            app.ReflowTemperatureSlider.Value = app.ReflowTemperatureSpinner.Value;
        end

        % Value changed function: ReflowTimesSpinner
        function ReflowTimesSpinnerValueChanged(app, event)
            app.ReflowTimesSlider.Value = app.ReflowTimesSpinner.Value;
        end

        % Value changed function: ErrorTimeoutsSpinner
        function ErrorTimeoutsSpinnerValueChanged(app, event)
            app.ErrorTimeoutsSlider.Value = app.ErrorTimeoutsSpinner.Value;
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            app.abortflag = 1;
            ClosePortSR(app);
        end

        % Value changed function: SoakingTimesSlider
        function SoakingTimesSliderValueChanged(app, event)
            app.SoakingTimesSlider.Value = round(app.SoakingTimesSlider.Value);
            app.SoakingTimesSpinner.Value = app.SoakingTimesSlider.Value;
        end

        % Value changed function: ReflowTemperatureSlider
        function ReflowTemperatureSliderValueChanged(app, event)
            app.ReflowTemperatureSlider.Value = round(app.ReflowTemperatureSlider.Value);
            app.ReflowTemperatureSpinner.Value = app.ReflowTemperatureSlider.Value;
        end

        % Value changed function: ReflowTimesSlider
        function ReflowTimesSliderValueChanged(app, event)
            value = app.ReflowTimesSlider.Value;
            app.ReflowTimesSlider.Value = round(app.ReflowTimesSlider.Value);
            app.ReflowTimesSpinner.Value = app.ReflowTimesSlider.Value;
        end

        % Value changed function: ErrorTimeoutsSlider
        function ErrorTimeoutsSliderValueChanged(app, event)
            value = app.ErrorTimeoutsSlider.Value;
            app.ErrorTimeoutsSlider.Value = round(app.ErrorTimeoutsSlider.Value);
            app.ErrorTimeoutsSpinner.Value = app.ErrorTimeoutsSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [999900 1000000];
            app.UIAxes.XTickLabel = '';
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {''};
            app.COMPortDropDown.Value = '';
            app.BuadRateEditFieldLabel = uilabel(app.UIFigure);
            app.BuadRateEditFieldLabel.Text = 'Buad Rate';
            app.BuadRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuadRateEditField.ValueDisplayFormat = '%11.4d';
            app.BuadRateEditField.Value = 115200;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ABORTButton = uibutton(app.UIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Visible = 'off';
            app.ABORTButton.Text = 'ABORT';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Visible = 'off';
            app.STARTButton.Text = 'START';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No COM port opened.'};
            app.OPENButton = uibutton(app.UIFigure, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.ProjectLabel = uilabel(app.UIFigure);
            app.ProjectLabel.Text = {'ELEC 291 - Electrical Engineering Design Studio I'; 'Project 1 - Reflow Oven Controller'; '2024.'};
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.SoakingTemperatureSpinner = uispinner(app.UIFigure);
            app.SoakingTemperatureSpinner.Limits = [0 400];
            app.SoakingTemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @SoakingTemperatureSpinnerValueChanged, true);
            app.SampleCountLabel = uilabel(app.UIFigure);
            app.SampleCountLabel.Text = {'Soaking'; 'Temperature'};
            app.SoakingTemperatureSlider = uislider(app.UIFigure);
            app.SoakingTemperatureSlider.Limits = [0 400];
            app.SoakingTemperatureSlider.ValueChangedFcn = createCallbackFcn(app, @SoakingTemperatureSliderValueChanged, true);
            app.SoakingTimesSpinner = uispinner(app.UIFigure);
            app.SoakingTimesSpinner.Limits = [0 120];
            app.SoakingTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @SoakingTimesSpinnerValueChanged, true);
            app.SoakingTimeLabel = uilabel(app.UIFigure);
            app.SoakingTimeLabel.Text = {'Soaking'; 'Time (s)'};
            app.SoakingTimesSlider = uislider(app.UIFigure);
            app.SoakingTimesSlider.Limits = [0 120];
            app.SoakingTimesSlider.ValueChangedFcn = createCallbackFcn(app, @SoakingTimesSliderValueChanged, true);
            app.ReflowTemperatureSpinner = uispinner(app.UIFigure);
            app.ReflowTemperatureSpinner.Limits = [0 400];
            app.ReflowTemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @ReflowTemperatureSpinnerValueChanged, true);
            app.ReflowTemperatureSliderLabel = uilabel(app.UIFigure);
            app.ReflowTemperatureSliderLabel.Text = {'Reflow'; 'Temperature'};
            app.ReflowTemperatureSlider = uislider(app.UIFigure);
            app.ReflowTemperatureSlider.Limits = [0 400];
            app.ReflowTemperatureSlider.ValueChangedFcn = createCallbackFcn(app, @ReflowTemperatureSliderValueChanged, true);
            app.ReflowTimesSpinner = uispinner(app.UIFigure);
            app.ReflowTimesSpinner.Limits = [0 120];
            app.ReflowTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @ReflowTimesSpinnerValueChanged, true);
            app.ReflowTimesSliderLabel = uilabel(app.UIFigure);
            app.ReflowTimesSliderLabel.Text = {'Reflow'; 'Time (s)'};
            app.ReflowTimesSlider = uislider(app.UIFigure);
            app.ReflowTimesSlider.Limits = [0 120];
            app.ReflowTimesSlider.ValueChangedFcn = createCallbackFcn(app, @ReflowTimesSliderValueChanged, true);
            app.ErrorTimeoutsSpinner = uispinner(app.UIFigure);
            app.ErrorTimeoutsSpinner.Limits = [0 120];
            app.ErrorTimeoutsSpinner.ValueChangedFcn = createCallbackFcn(app, @ErrorTimeoutsSpinnerValueChanged, true);
            app.ErrorTimeoutsSliderLabel = uilabel(app.UIFigure);
            app.ErrorTimeoutsSliderLabel.Text = {'Error'; 'Timeout (s)'};
            app.ErrorTimeoutsSlider = uislider(app.UIFigure);
            app.ErrorTimeoutsSlider.Limits = [0 120];
            app.ErrorTimeoutsSlider.ValueChangedFcn = createCallbackFcn(app, @ErrorTimeoutsSliderValueChanged, true);
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Visible = 'off';
            app.CLOSEButton.Text = 'CLOSE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELEC_291_Project_1_Reflow_Oven_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end